classdef APP_Perfect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TabTimNghiem                    matlab.ui.container.Tab
        KtQuPanel_4                     matlab.ui.container.Panel
        KtqunghimEditField              matlab.ui.control.EditField
        KtqunghimEditFieldLabel         matlab.ui.control.Label
        SlnlpEditField                  matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel             matlab.ui.control.Label
        NhpDLiuuVoPanel_4               matlab.ui.container.Panel
        KhongphnlynghimEditField        matlab.ui.control.EditField
        KhongphnlynghimEditFieldLabel   matlab.ui.control.Label
        KtquButton                      matlab.ui.control.Button
        ChnphngphpDropDown              matlab.ui.control.DropDown
        ChnphngphpDropDownLabel         matlab.ui.control.Label
        NhpsaisEditField                matlab.ui.control.NumericEditField
        NhpsaisEditFieldLabel           matlab.ui.control.Label
        NhpphngtrnhEditField            matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel       matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
        TabHoiQuy                       matlab.ui.container.Tab
        KtQuPanel                       matlab.ui.container.Panel
        R2_TextArea                     matlab.ui.control.TextArea
        HstngquanR2Label                matlab.ui.control.Label
        Equation_Result_TextArea        matlab.ui.control.TextArea
        KtquphngtrnhHiquyLabel          matlab.ui.control.Label
        Predict_Result_TextArea         matlab.ui.control.TextArea
        KtqudonTextAreaLabel            matlab.ui.control.Label
        NhpDLiuuVoPanel                 matlab.ui.container.Panel
        X_EditField                     matlab.ui.control.EditField
        NhpdliuxEditFieldLabel          matlab.ui.control.Label
        Y_EditField                     matlab.ui.control.EditField
        NhpdliuyEditFieldLabel          matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        PhngphpHiquyDropDownLabel       matlab.ui.control.Label
        KtquButton_2                    matlab.ui.control.Button
        Predict_Value_EditField         matlab.ui.control.EditField
        NhpgitrcndonEditFieldLabel      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        TabNoiSuy                       matlab.ui.container.Tab
        KtquathcnisuyEditField          matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel     matlab.ui.control.Label
        KetquadathucnoisuyEditField     matlab.ui.control.NumericEditField
        gitrathcnisuyLabel              matlab.ui.control.Label
        KetquanoisuyButton              matlab.ui.control.Button
        NHPDLIUUVOPanel                 matlab.ui.container.Panel
        NhpgitrcnnisuyEditField         matlab.ui.control.EditField
        NhpgitrcnnisuyEditFieldLabel    matlab.ui.control.Label
        ChonphuongphapnoisuyDropDown    matlab.ui.control.DropDown
        PhngphpnisuyLabel               matlab.ui.control.Label
        NhapdulieuyEditField            matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_2        matlab.ui.control.Label
        NhapdulieuxEditField            matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_2        matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        OHMTab                          matlab.ui.container.Tab
        KTQUPanel                       matlab.ui.container.Panel
        KetQua_2                        matlab.ui.control.NumericEditField
        KtquEditFieldLabel_2            matlab.ui.control.Label
        Bc2NhpDLiuPanel                 matlab.ui.container.Panel
        RunButton_2                     matlab.ui.control.Button
        phuongphapdaoham_2              matlab.ui.control.DropDown
        PhngPhpoHmLabel_2               matlab.ui.control.Label
        NhapX_2                         matlab.ui.control.NumericEditField
        NhpimxLabel_2                   matlab.ui.control.Label
        chongiatrisaiso_2               matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel_2      matlab.ui.control.Label
        buocnhay_2                      matlab.ui.control.NumericEditField
        BcnhyhLabel_2                   matlab.ui.control.Label
        Bc1ChnLoioHmHmSHocimPanel       matlab.ui.container.Panel
        ChnloiohmDropDown               matlab.ui.control.DropDown
        ChnloiohmDropDownLabel          matlab.ui.control.Label
        NhapdulieuxEditField_2          matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_3        matlab.ui.control.Label
        NhapdulieuyEditField_2          matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_3        matlab.ui.control.Label
        Nhaphamso_2                     matlab.ui.control.EditField
        NhphmsEditFieldLabel_2          matlab.ui.control.Label
        TabTichPhan                     matlab.ui.container.Tab
        KtQuPanel_3                     matlab.ui.container.Panel
        inputN                          matlab.ui.control.TextArea
        nhapN                           matlab.ui.control.Label
        lowerbound                      matlab.ui.control.TextArea
        canduoi                         matlab.ui.control.Label
        cantren                         matlab.ui.control.Label
        chonphuongphap                  matlab.ui.control.Label
        method                          matlab.ui.control.DropDown
        upperbound                      matlab.ui.control.TextArea
        NhpDLiuuVoPanel_5               matlab.ui.container.Panel
        nhaphamso                       matlab.ui.control.Label
        inputFx                         matlab.ui.control.TextArea
        nhapdulieuY                     matlab.ui.control.Label
        inputY                          matlab.ui.control.TextArea
        nhapdulieuX                     matlab.ui.control.Label
        inputX                          matlab.ui.control.TextArea
        calc                            matlab.ui.control.Button
        UIAxes_3                        matlab.ui.control.UIAxes
        TabGioiThieu                    matlab.ui.container.Tab
        NguynTrnHongPhcMSSV22207128Panel  matlab.ui.container.Panel
        Image4                          matlab.ui.control.Image
        NguynTrnHongPhcMSSV22207128TextArea  matlab.ui.control.TextArea
        LQuangThng23207113Panel         matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        LQuangThngMSSV23207113TextArea  matlab.ui.control.TextArea
        GiaoHuLc23207080Panel           matlab.ui.container.Panel
        Image2                          matlab.ui.control.Image
        GiaoHuLcMSSV23207080TextArea    matlab.ui.control.TextArea
        ngBTrnTrung23207121Panel        matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        ngBTrnTrungMSSV23207121TextArea  matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KtquButton_2
        function KtquButton_2Pushed(app, event)
            eq = "";
            r2 = 0;
            a0 = 0; a1 = 0;

            %% 1. Đọc dữ liệu X, Y
            try
                x = sscanf(app.X_EditField.Value, '%f');
                y = sscanf(app.Y_EditField.Value, '%f');
            catch
                app.Equation_Result_TextArea.Value = {'Lỗi định dạng số'};
                return;
            end

            if isempty(x) || isempty(y)
                app.Equation_Result_TextArea.Value = {'Không có dữ liệu'};
                return;
            end

            if length(x) ~= length(y)
                app.Equation_Result_TextArea.Value = {'X và Y khác kích thước'};
                return;
            end

            x = x(:);
            y = y(:);
            n = length(x);

            %% 2. Chọn phương pháp hồi quy
            method = string(app.MethodDropDown.Value);

            try
                switch method

                    % ================= TUYẾN TÍNH =================
                    case "Tuyến Tính"
                        p = polyfit(x, y, 1);
                        a1 = p(1);
                        a0 = p(2);

                        y_fit = polyval(p, x);
                        r2 = 1 - sum((y - y_fit).^2) / ((n-1)*var(y));

                        eq = sprintf("y = %.4f + %.4f x", a0, a1);

                        xx = linspace(min(x), max(x), 100);
                        yy = a0 + a1 * xx;

                    % ================= HÀM MŨ =====================
                    case "Hàm Mũ"
                        if any(x <= 0) || any(y <= 0)
                            error('X, Y phải > 0');
                        end

                        ln_x = log(x);
                        ln_y = log(y);
                        p = polyfit(ln_x, ln_y, 1);

                        a1 = p(1);
                        a0 = exp(p(2));

                        y_fit = polyval(p, ln_x);
                        r2 = 1 - sum((ln_y - y_fit).^2) / ((n-1)*var(ln_y));

                        eq = sprintf("y = %.4f * x^{%.4f}", a0, a1);

                        xx = linspace(min(x), max(x), 100);
                        yy = a0 * (xx.^a1);

                    % ================= HÀM MŨ e =================
                    case "Hàm Mũ e"
                        if any(y <= 0)
                            error('Y phải > 0');
                        end

                        ln_y = log(y);
                        p = polyfit(x, ln_y, 1);

                        a1 = p(1);
                        a0 = exp(p(2));

                        y_fit = polyval(p, x);
                        r2 = 1 - sum((ln_y - y_fit).^2) / ((n-1)*var(ln_y));

                        eq = sprintf("y = %.4f * e^{%.4f x}", a0, a1);

                        xx = linspace(min(x), max(x), 100);
                        yy = a0 * exp(a1 * xx);
                end

                %% 3. Hiển thị kết quả
                app.Equation_Result_TextArea.Value = {char(eq)};
                app.R2_TextArea.Value = {num2str(r2,'%.6f')};

                %% 4. Vẽ đồ thị
                cla(app.UIAxes);
                plot(app.UIAxes, x, y, 'ro', 'MarkerFaceColor','r');
                hold(app.UIAxes,'on');
                plot(app.UIAxes, xx, yy, 'b-', 'LineWidth',2);
                grid(app.UIAxes,'on');
                legend(app.UIAxes, {'Dữ liệu','Hồi quy'});
                hold(app.UIAxes,'off');

                %% 5. Dự đoán giá trị
                xp = str2double(app.Predict_Value_EditField.Value);

                if ~isnan(xp)
                    if method=="Tuyến Tính"
                        yp = a0 + a1*xp;
                    elseif method=="Hàm Mũ"
                        if xp<=0
                            error('X dự đoán phải > 0');
                        end
                        yp = a0 * xp^a1;
                    else
                        yp = a0 * exp(a1*xp);
                    end

                    app.Predict_Result_TextArea.Value = {num2str(yp,'%.6f')};
                else
                    app.Predict_Result_TextArea.Value = {''};
                end

            catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi');
            end
        end

        % Callback function
        function KetquanoisuyButtonPushed(app, event)

        end

        % Callback function: not associated with a component
        function RunButtonPushed(app, event)
           
        end

        % Button pushed function: calc
        function calcButtonPushed(app, event)
            mtd = app.method.Value;
            fxText = app.inputFx.Value;
            xTextcell = app.inputX.Value;
            yTextcell = app.inputY.Value;

            a = str2double(app.lowerbound.Value);
            b = str2double(app.upperbound.Value);
            N = str2double(app.inputN.Value);
            
            cla(app.UIAxes);

            if ~isempty(xTextcell) && ~isempty(yTextcell) && ~isempty(strtrim(xTextcell{1})) && ~isempty(strtrim(yTextcell{1}))
                x = str2num(xTextcell{1});
                y = str2num(yTextcell{1});  
                if strcmp(mtd, 'Phương pháp hình thang')
                    I = hinhthang_xy(x, y);
                else
                    uialert(app.UIFigure, "Phương pháp Simpson không áp dụng cho dữ liệu mảng.", "Lỗi");
                    return;
                end
                
                plot(app.UIAxes_3, x, y, 'b-', "LineWidth", 1.5);
                title(app.UIAxes_3, 'Đồ thị dữ liệu x - y');
                grid(app.UIAxes_3, 'on');
                uialert(app.UIFigure, sprintf("Tích phân = %.6f", I), "Kết quả");
                return;
            end

            if ~isempty(fxText)
                f = str2func(['@(x)' fxText{1}]);
                switch mtd
                    case 'Phương pháp hình thang'
                        I = hinhthang_f(f, a, b, N);

                    case 'Phương pháp Simpson 1/3'
                        I = simpson13(f, a, b, N);

                    case 'Phương pháp Simpson 3/8'
                        I = simpson38(f, a, b, N);
                end

                fplot(app.UIAxes_3, f, [a b], "LineWidth", 1.5);
                title(app.UIAxes_3, ['Tích phân của f(x) trên [' num2str(a) ',' num2str(b) ']']);
                grid(app.UIAxes_3, 'on');
                uialert(app.UIFigure, sprintf("Tích phân = %.6f", I), "Kết quả");
            end
        end

        % Button pushed function: KtquButton
        function KtquButtonPushed(app, event)
           %% ===== LẤY DỮ LIỆU NHẬP =====
    fstr = app.NhpphngtrnhEditField.Value;

    % sửa để vector hóa
    fstr = strrep(fstr, '^', '.^');
    fstr = strrep(fstr, '*', '.*');
    fstr = strrep(fstr, '/', './');

    % tạo hàm f(x)
    f = str2func(['@(x) ' fstr]);

    interval = app.KhongphnlynghimEditField.Value;
    eps = app.NhpsaisEditField.Value;
    method = app.ChnphngphpDropDown.Value;

    % tách khoảng a,b
    interval = split(interval, ',');
    a = str2double(interval{1});
    b = str2double(interval{2});

    %% ===== CHỌN PHƯƠNG PHÁP =====
    switch method

        case 'Chia đôi'
            [root, iter] = Bisection(f, a, b, eps);

        case 'Lặp'
            gstr = inputdlg('Nhập g(x):');
            g = str2func(['@(x) ' gstr{1}]);
            [root, iter] = FixedPoint(g, a, eps);

        case 'Newton'
            syms x
            f_sym = str2sym(fstr);
            df = matlabFunction(diff(f_sym, x));
            [root, iter] = NewtonMethod(f, df, a, eps);

        otherwise
            uialert(app.UIFigure,'Bạn chưa chọn phương pháp','Lỗi');
            return
    end

    %% ===== HIỂN THỊ KẾT QUẢ =====
    app.KtqunghimEditField.Value = num2str(root);
    app.SlnlpEditField.Value = iter;

    %% ===== VẼ ĐỒ THỊ =====
    cla(app.UIAxes_4)
    xx = linspace(a-1, b+1, 400);
    yy = f(xx);

    plot(app.UIAxes_4, xx, yy, 'LineWidth', 2)
    hold(app.UIAxes_4, 'on')
    plot(app.UIAxes_4, root, f(root), 'ro', ...
        'MarkerSize', 8, 'MarkerFaceColor', 'r')
    hold(app.UIAxes_4, 'off')
    grid(app.UIAxes_4, 'on')


        end

        % Button pushed function: KetquanoisuyButton
        function KetquanoisuyButtonPushed2(app, event)
            try
               X_str = regexprep(app.NhapdulieuxEditField.Value, ',', ' ');
               Y_str = regexprep(app.NhapdulieuyEditField.Value, ',', ' ');

               X = sscanf(X_str, '%f')';
               Y = sscanf(Y_str, '%f')';

               if isempty(X) || isempty(Y) || length(X) ~= length(Y)
                   uialert(app.UIFigure, 'Dữ liệu X và Y phải cùng số phần tử.', 'Lỗi dữ liệu');
                   return;
               end

               if length(unique(X)) ~= length(X)
                   uialert(app.UIFigure, 'Các giá trị X phải phân biệt nhau.', 'Lỗi dữ liệu');
                   return;
               end
           catch
               uialert(app.UIFigure, 'Lỗi khi đọc dữ liệu X, Y.', 'Lỗi nhập liệu');
               return;
           end

           x_str = app.NhpgitrcnnisuyEditField.Value;
           
           try
               f = str2func(['@() ' x_str]);
               x_interp = f();
               if ~isscalar(x_interp) || isnan(x_interp) || isinf(x_interp)
                   error('Giá trị không hợp lệ');
               end

           catch
               uialert(app.UIFigure, 'Giá trị nội suy phải là số hoặc biểu thức hợp lệ (vd: sin(0.14), pi/6, 0.46).', 'Lỗi nhập liệu');
               return;
           end

           method = app.ChonphuongphapnoisuyDropDown.Value;

           switch method
               case 'Lagrange'
                   [result, poly_str] = lagrange_interp(X, Y, x_interp);
                   f = @(t) lagrange_interp(X, Y, t);
               case 'Nội suy Newton'
                   [result, poly_str] = newton_interp(X, Y, x_interp);
                   f = @(t) newton_interp(X, Y, t);
           end

           app.KetquadathucnoisuyEditField.Value = result;
           app.KtquathcnisuyEditField.Value = poly_str;
           
           cla(app.UIAxes_5)
           hold(app.UIAxes_5, 'on')
           plot(app.UIAxes_5, X, Y, 'ro', 'MarkerSize', 8, 'LineWidth', 1.5)

           xx = linspace(min(X), max(X), 500);
           yy = zeros(size(xx));
           for i = 1:length(xx)
               yy(i) = lagrange_interp(X, Y, xx(i));
           end

           plot(app.UIAxes_5, xx, yy, 'b-', 'LineWidth', 1.5)
           plot(app.UIAxes_5, x_interp, result, 'ks', 'MarkerSize', 10, 'LineWidth', 2)
           
           legend(app.UIAxes_5, 'Dữ liệu gốc', 'Đa thức nội suy', 'Điểm nội suy', 'Location', 'best')
           grid(app.UIAxes_5, 'on')
           hold(app.UIAxes_5, 'off');
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            try
                loaiDaoHam = string(app.ChnloiohmDropDown.Value);

                %% ===== ĐẠO HÀM BẢNG SỐ LIỆU =====
                if loaiDaoHam == "đạo hàm điểm"
                    x_data = str2double(split(app.NhapdulieuxEditField_2.Value)); 
                    y_data = str2double(split(app.NhapdulieuyEditField_2.Value));
                    
                    if isempty(x_data) || isempty(y_data)
                        error('Dữ liệu x hoặc y không hợp lệ. Hãy nhập dạng: 1 2 3');
                    end
                    if length(x_data) ~= length(y_data)
                        error('Độ dài vector x và y phải bằng nhau');
                    end

                    i = round(app.NhapX_2.Value);
                    if length(x_data) > 1
                        h = x_data(2) - x_data(1);
                    else
                        h = app.buocnhay_2.Value;
                    end
                    method = string(app.phuongphapdaoham_2.Value);

                    KetQuaTinh = tinh_daoham_bang(y_data, i, h, method);
                    app.KetQua_2.Value = KetQuaTinh;
                    return;
                end

                %% ===== ĐẠO HÀM HÀM SỐ =====
                strHam = string(app.Nhaphamso_2.Value);
                x0 = app.NhapX_2.Value;
                h  = app.buocnhay_2.Value;
                method = string(app.phuongphapdaoham_2.Value);
                errorOrder = string(app.chongiatrisaiso_2.Value);

                % Chuẩn hóa toán tử
                strHam = strrep(strHam, '^', '.^');
                strHam = strrep(strHam, '*', '.*');
                strHam = strrep(strHam, '/', './');

                f = str2func(['@(x)' char(strHam)]);

                switch method
                    case 'Newton tiến'
                        KetQuaTinh = tinh_newton_tien(f, x0, h, errorOrder);
                    case 'Newton lùi'
                        KetQuaTinh = tinh_newton_lui(f, x0, h, errorOrder);
                    case 'Newton trung tâm'
                        KetQuaTinh = tinh_trung_tam(f, x0, h, errorOrder);
                end

                app.KetQua_2.Value = KetQuaTinh;

            catch e
                uialert(app.UIFigure, e.message, 'Lỗi');
            end
        end

        % Value changed function: ChnloiohmDropDown
        function ChnloiohmDropDownValueChanged(app, event)
            value = app.ChnloiohmDropDown.Value;
            if app.ChnloiohmDropDown.Value == "đạo hàm điểm"
                app.NhpimxLabel_2.Text = 'Nhập chỉ số i';
                app.NhphmsEditFieldLabel_2.Text = 'Nhập vector y';
            else
                app.NhpimxLabel_2.Text = 'Nhập điểm x';
                app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabTimNghiem = uitab(app.TabGroup);
            app.TabTimNghiem.Title = 'TÌM NGHIỆM';
            app.UIAxes_4 = uiaxes(app.TabTimNghiem);
            title(app.UIAxes_4, 'Đồ thị hàm số')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.NhpDLiuuVoPanel_4 = uipanel(app.TabTimNghiem);
            app.NhpDLiuuVoPanel_4.Title = 'Nhập Dữ Liệu Đầu Vào';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NhpDLiuuVoPanel_4);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.NhpphngtrnhEditField = uieditfield(app.NhpDLiuuVoPanel_4, 'text');
            app.NhpsaisEditFieldLabel = uilabel(app.NhpDLiuuVoPanel_4);
            app.NhpsaisEditFieldLabel.Text = 'Nhập sai số';
            app.NhpsaisEditField = uieditfield(app.NhpDLiuuVoPanel_4, 'numeric');
            app.NhpsaisEditField.AllowEmpty = 'on';
            app.NhpsaisEditField.Value = [];
            app.ChnphngphpDropDownLabel = uilabel(app.NhpDLiuuVoPanel_4);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.ChnphngphpDropDown = uidropdown(app.NhpDLiuuVoPanel_4);
            app.ChnphngphpDropDown.Items = {'Chia đôi', 'Lặp', 'Newton', ''};
            app.ChnphngphpDropDown.Value = 'Chia đôi';
            app.KtquButton = uibutton(app.NhpDLiuuVoPanel_4, 'push');
            app.KtquButton.ButtonPushedFcn = createCallbackFcn(app, @KtquButtonPushed, true);
            app.KtquButton.Text = 'Kết quả';
            app.KhongphnlynghimEditFieldLabel = uilabel(app.NhpDLiuuVoPanel_4);
            app.KhongphnlynghimEditFieldLabel.Text = 'Khoảng phân ly nghiệm';
            app.KhongphnlynghimEditField = uieditfield(app.NhpDLiuuVoPanel_4, 'text');
            app.KtQuPanel_4 = uipanel(app.TabTimNghiem);
            app.KtQuPanel_4.Title = 'Kết Quả';
            app.SlnlpEditFieldLabel = uilabel(app.KtQuPanel_4);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.SlnlpEditField = uieditfield(app.KtQuPanel_4, 'numeric');
            app.SlnlpEditField.AllowEmpty = 'on';
            app.SlnlpEditField.Value = [];
            app.KtqunghimEditFieldLabel = uilabel(app.KtQuPanel_4);
            app.KtqunghimEditFieldLabel.Text = 'Kết quả nghiệm';
            app.KtqunghimEditField = uieditfield(app.KtQuPanel_4, 'text');
            app.TabHoiQuy = uitab(app.TabGroup);
            app.TabHoiQuy.Title = 'HỒI QUY';
            app.UIAxes = uiaxes(app.TabHoiQuy);
            title(app.UIAxes, 'Đồ Thị')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpDLiuuVoPanel = uipanel(app.TabHoiQuy);
            app.NhpDLiuuVoPanel.Title = 'Nhập Dữ Liệu Đầu Vào';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.NhpDLiuuVoPanel);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.Predict_Value_EditField = uieditfield(app.NhpDLiuuVoPanel, 'text');
            app.KtquButton_2 = uibutton(app.NhpDLiuuVoPanel, 'push');
            app.KtquButton_2.ButtonPushedFcn = createCallbackFcn(app, @KtquButton_2Pushed, true);
            app.KtquButton_2.Text = 'Kết quả';
            app.PhngphpHiquyDropDownLabel = uilabel(app.NhpDLiuuVoPanel);
            app.PhngphpHiquyDropDownLabel.Text = 'Phương pháp Hồi quy';
            app.MethodDropDown = uidropdown(app.NhpDLiuuVoPanel);
            app.MethodDropDown.Items = {'Tuyến Tính', 'Hàm Mũ', 'Hàm Mũ e'};
            app.MethodDropDown.Value = 'Tuyến Tính';
            app.NhpdliuyEditFieldLabel = uilabel(app.NhpDLiuuVoPanel);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.Y_EditField = uieditfield(app.NhpDLiuuVoPanel, 'text');
            app.NhpdliuxEditFieldLabel = uilabel(app.NhpDLiuuVoPanel);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.X_EditField = uieditfield(app.NhpDLiuuVoPanel, 'text');
            app.KtQuPanel = uipanel(app.TabHoiQuy);
            app.KtQuPanel.Title = 'Kết Quả';
            app.KtqudonTextAreaLabel = uilabel(app.KtQuPanel);
            app.KtqudonTextAreaLabel.Text = 'Kết quả dự đoán';
            app.Predict_Result_TextArea = uitextarea(app.KtQuPanel);
            app.KtquphngtrnhHiquyLabel = uilabel(app.KtQuPanel);
            app.KtquphngtrnhHiquyLabel.Text = {'Kết quả '; 'phương trình Hồi quy'};
            app.Equation_Result_TextArea = uitextarea(app.KtQuPanel);
            app.HstngquanR2Label = uilabel(app.KtQuPanel);
            app.HstngquanR2Label.Text = {'Hệ số '; 'tương quan R^2'};
            app.R2_TextArea = uitextarea(app.KtQuPanel);
            app.TabNoiSuy = uitab(app.TabGroup);
            app.TabNoiSuy.Title = 'NỘI SUY';
            app.UIAxes_5 = uiaxes(app.TabNoiSuy);
            title(app.UIAxes_5, 'Tín hiệu')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.NHPDLIUUVOPanel = uipanel(app.TabNoiSuy);
            app.NHPDLIUUVOPanel.Title = 'NHẬP DỮ LIỆU ĐẦU VÀO';
            app.NhpdliuxEditFieldLabel_2 = uilabel(app.NHPDLIUUVOPanel);
            app.NhpdliuxEditFieldLabel_2.Text = 'Nhập dữ liệu x';
            app.NhapdulieuxEditField = uieditfield(app.NHPDLIUUVOPanel, 'text');
            app.NhpdliuyEditFieldLabel_2 = uilabel(app.NHPDLIUUVOPanel);
            app.NhpdliuyEditFieldLabel_2.Text = 'Nhập dữ liệu y';
            app.NhapdulieuyEditField = uieditfield(app.NHPDLIUUVOPanel, 'text');
            app.PhngphpnisuyLabel = uilabel(app.NHPDLIUUVOPanel);
            app.PhngphpnisuyLabel.Text = 'Phương pháp nội suy';
            app.ChonphuongphapnoisuyDropDown = uidropdown(app.NHPDLIUUVOPanel);
            app.ChonphuongphapnoisuyDropDown.Items = {'Nội suy Newton', 'Lagrange'};
            app.ChonphuongphapnoisuyDropDown.Value = 'Lagrange';
            app.NhpgitrcnnisuyEditFieldLabel = uilabel(app.NHPDLIUUVOPanel);
            app.NhpgitrcnnisuyEditFieldLabel.Text = 'Nhập giá trị cần nội suy';
            app.NhpgitrcnnisuyEditField = uieditfield(app.NHPDLIUUVOPanel, 'text');
            app.KetquanoisuyButton = uibutton(app.TabNoiSuy, 'push');
            app.KetquanoisuyButton.ButtonPushedFcn = createCallbackFcn(app, @KetquanoisuyButtonPushed2, true);
            app.KetquanoisuyButton.Text = 'Kết quả nội suy';
            app.gitrathcnisuyLabel = uilabel(app.TabNoiSuy);
            app.gitrathcnisuyLabel.Text = 'Giá trị đa thức nội suy';
            app.KetquadathucnoisuyEditField = uieditfield(app.TabNoiSuy, 'numeric');
            app.KtquathcnisuyEditFieldLabel = uilabel(app.TabNoiSuy);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KtquathcnisuyEditField = uieditfield(app.TabNoiSuy, 'text');
            app.OHMTab = uitab(app.TabGroup);
            app.OHMTab.Title = 'ĐẠO HÀM';
            app.Bc1ChnLoioHmHmSHocimPanel = uipanel(app.OHMTab);
            app.Bc1ChnLoioHmHmSHocimPanel.Title = 'Bước 1: Chọn Loại Đạo Hàm: Hàm Số Hoặc Điểm';
            app.NhphmsEditFieldLabel_2 = uilabel(app.Bc1ChnLoioHmHmSHocimPanel);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.Nhaphamso_2 = uieditfield(app.Bc1ChnLoioHmHmSHocimPanel, 'text');
            app.NhpdliuyEditFieldLabel_3 = uilabel(app.Bc1ChnLoioHmHmSHocimPanel);
            app.NhpdliuyEditFieldLabel_3.Text = 'Nhập dữ liệu y';
            app.NhapdulieuyEditField_2 = uieditfield(app.Bc1ChnLoioHmHmSHocimPanel, 'text');
            app.NhpdliuxEditFieldLabel_3 = uilabel(app.Bc1ChnLoioHmHmSHocimPanel);
            app.NhpdliuxEditFieldLabel_3.Text = 'Nhập dữ liệu x';
            app.NhapdulieuxEditField_2 = uieditfield(app.Bc1ChnLoioHmHmSHocimPanel, 'text');
            app.ChnloiohmDropDownLabel = uilabel(app.Bc1ChnLoioHmHmSHocimPanel);
            app.ChnloiohmDropDownLabel.Text = 'Chọn loại đạo hàm';
            app.ChnloiohmDropDown = uidropdown(app.Bc1ChnLoioHmHmSHocimPanel);
            app.ChnloiohmDropDown.Items = {'đạo hàm hàm số', 'đạo hàm điểm'};
            app.ChnloiohmDropDown.Value = 'đạo hàm điểm';
            app.Bc2NhpDLiuPanel = uipanel(app.OHMTab);
            app.Bc2NhpDLiuPanel.Title = 'Bước 2: Nhập Dữ Liệu';
            app.BcnhyhLabel_2 = uilabel(app.Bc2NhpDLiuPanel);
            app.BcnhyhLabel_2.Text = 'Bước nhảy (h)';
            app.buocnhay_2 = uieditfield(app.Bc2NhpDLiuPanel, 'numeric');
            app.buocnhay_2.AllowEmpty = 'on';
            app.buocnhay_2.Value = [];
            app.ChngitrsaisDropDownLabel_2 = uilabel(app.Bc2NhpDLiuPanel);
            app.ChngitrsaisDropDownLabel_2.Text = 'Chọn giá trị sai số';
            app.chongiatrisaiso_2 = uidropdown(app.Bc2NhpDLiuPanel);
            app.chongiatrisaiso_2.Items = {'O(h)', 'O(h^2)'};
            app.chongiatrisaiso_2.Value = 'O(h)';
            app.NhpimxLabel_2 = uilabel(app.Bc2NhpDLiuPanel);
            app.NhpimxLabel_2.Text = 'Nhập điểm x';
            app.NhapX_2 = uieditfield(app.Bc2NhpDLiuPanel, 'numeric');
            app.NhapX_2.AllowEmpty = 'on';
            app.NhapX_2.Value = [];
            app.PhngPhpoHmLabel_2 = uilabel(app.Bc2NhpDLiuPanel);
            app.PhngPhpoHmLabel_2.Text = 'Phương Pháp Đạo Hàm';
            app.phuongphapdaoham_2 = uidropdown(app.Bc2NhpDLiuPanel);
            app.phuongphapdaoham_2.Items = {'Newton tiến', 'Newton lùi', 'Newton trung tâm'};
            app.phuongphapdaoham_2.Value = 'Newton tiến';
            app.RunButton_2 = uibutton(app.Bc2NhpDLiuPanel, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.KTQUPanel = uipanel(app.OHMTab);
            app.KTQUPanel.Title = 'KẾT QUẢ';
            app.KtquEditFieldLabel_2 = uilabel(app.KTQUPanel);
            app.KtquEditFieldLabel_2.Text = 'Kết quả';
            app.KetQua_2 = uieditfield(app.KTQUPanel, 'numeric');
            app.TabTichPhan = uitab(app.TabGroup);
            app.TabTichPhan.Title = 'TÍCH PHÂN';
            app.UIAxes_3 = uiaxes(app.TabTichPhan);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.calc = uibutton(app.TabTichPhan, 'push');
            app.calc.ButtonPushedFcn = createCallbackFcn(app, @calcButtonPushed, true);
            app.calc.Text = 'Kết quả';
            app.NhpDLiuuVoPanel_5 = uipanel(app.TabTichPhan);
            app.NhpDLiuuVoPanel_5.Title = 'Nhập Dữ Liệu Đầu Vào';
            app.inputX = uitextarea(app.NhpDLiuuVoPanel_5);
            app.nhapdulieuX = uilabel(app.NhpDLiuuVoPanel_5);
            app.nhapdulieuX.Text = 'NHẬP DỮ LIỆU X';
            app.inputY = uitextarea(app.NhpDLiuuVoPanel_5);
            app.nhapdulieuY = uilabel(app.NhpDLiuuVoPanel_5);
            app.nhapdulieuY.Text = 'NHẬP DỮ LIỆU Y';
            app.inputFx = uitextarea(app.NhpDLiuuVoPanel_5);
            app.nhaphamso = uilabel(app.NhpDLiuuVoPanel_5);
            app.nhaphamso.Text = 'NHẬP HÀM SỐ CẦN TÍNH';
            app.KtQuPanel_3 = uipanel(app.TabTichPhan);
            app.KtQuPanel_3.Title = 'Kết Quả';
            app.upperbound = uitextarea(app.KtQuPanel_3);
            app.method = uidropdown(app.KtQuPanel_3);
            app.method.Items = {'Phương pháp hình thang', 'Phương pháp Simpson 1/3', 'Phương pháp Simpson 3/8'};
            app.method.Value = 'Phương pháp hình thang';
            app.chonphuongphap = uilabel(app.KtQuPanel_3);
            app.chonphuongphap.Text = 'CHỌN PHƯƠNG PHÁP TÍNH';
            app.cantren = uilabel(app.KtQuPanel_3);
            app.cantren.Text = 'CẬN TRÊN';
            app.canduoi = uilabel(app.KtQuPanel_3);
            app.canduoi.Text = 'CẬN DƯỚI';
            app.lowerbound = uitextarea(app.KtQuPanel_3);
            app.nhapN = uilabel(app.KtQuPanel_3);
            app.nhapN.Text = 'NHẬP N';
            app.inputN = uitextarea(app.KtQuPanel_3);
            app.TabGioiThieu = uitab(app.TabGroup);
            app.TabGioiThieu.Title = 'GIỚI THIỆU THÀNH VIÊN';
            app.ngBTrnTrung23207121Panel = uipanel(app.TabGioiThieu);
            app.ngBTrnTrung23207121Panel.Title = 'Đặng Bá Trần Trung - 23207121';
            app.ngBTrnTrungMSSV23207121TextArea = uitextarea(app.ngBTrnTrung23207121Panel);
            app.ngBTrnTrungMSSV23207121TextArea.Value = {'- Thực hiện code Matlab của phần Hồi quy và phần báo cáo mục Hồi quy.'; '- Thực hiện code Matlab của phần Đạo hàm và phần báo cáo mục Đạo hàm.'; '- Tổng hợp các code Matlab lại thành app hoàn chỉnh gồm nhiều tab.'};
            app.Image = uiimage(app.ngBTrnTrung23207121Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, '594984395_3289804884519619_624763340338059162_n.png');
            app.GiaoHuLc23207080Panel = uipanel(app.TabGioiThieu);
            app.GiaoHuLc23207080Panel.Title = 'Giao Hữu Lực - 23207080';
            app.GiaoHuLcMSSV23207080TextArea = uitextarea(app.GiaoHuLc23207080Panel);
            app.GiaoHuLcMSSV23207080TextArea.Value = {'- Thực hiện code Matlab của phần Tích phân và phần báo cáo mục Tích phân.'; '- Tổng hợp và chỉnh sửa các phần báo cáo của từng mục lại thành một bài báo cáo word hoàn chỉnh.'};
            app.Image2 = uiimage(app.GiaoHuLc23207080Panel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '597123896_2199652603777767_4657481350121714540_n.png');
            app.LQuangThng23207113Panel = uipanel(app.TabGioiThieu);
            app.LQuangThng23207113Panel.Title = 'Lê Quang Thông - 23207113';
            app.LQuangThngMSSV23207113TextArea = uitextarea(app.LQuangThng23207113Panel);
            app.LQuangThngMSSV23207113TextArea.Value = {'- Thực hiện code Matlab của phần Nội suy và phần báo cáo mục Nội suy.'};
            app.Image3 = uiimage(app.LQuangThng23207113Panel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '593526237_730604760099694_1679685577997720704_n.png');
            app.NguynTrnHongPhcMSSV22207128Panel = uipanel(app.TabGioiThieu);
            app.NguynTrnHongPhcMSSV22207128Panel.Title = 'Nguyễn Trần Hoàng Phúc MSSV - 22207128';
            app.NguynTrnHongPhcMSSV22207128TextArea = uitextarea(app.NguynTrnHongPhcMSSV22207128Panel);
            app.NguynTrnHongPhcMSSV22207128TextArea.Value = {'- Thực hiện code Matlab của phần Nội suy và phần báo cáo mục Nội suy.'};
            app.Image4 = uiimage(app.NguynTrnHongPhcMSSV22207128Panel);
            app.Image4.ImageSource = fullfile(pathToMLAPP, '597895486_1866003187623102_7538884082448921255_n.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_Perfect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end